classdef main_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GeneticAlgorithmPanel           matlab.ui.container.Panel
        NumberofdecisionvariablesEditFieldLabel  matlab.ui.control.Label
        NumberofdecisionvariablesEditField  matlab.ui.control.NumericEditField
        MaximumnumberofIterationEditFieldLabel  matlab.ui.control.Label
        MaximumnumberofIterationEditField  matlab.ui.control.NumericEditField
        PopulationPanel                 matlab.ui.container.Panel
        PopulationSizeEditFieldLabel    matlab.ui.control.Label
        PopulationSizeEditField         matlab.ui.control.NumericEditField
        SelectionPanel                  matlab.ui.container.Panel
        SelectionFunctionDropDownLabel  matlab.ui.control.Label
        SelectionFunctionDropDown       matlab.ui.control.DropDown
        TournamentSizeEditFieldLabel    matlab.ui.control.Label
        TournamentSizeEditField         matlab.ui.control.NumericEditField
        CrossoverPanel                  matlab.ui.container.Panel
        CrossoverPercentageEditFieldLabel  matlab.ui.control.Label
        CrossoverPercentageEditField    matlab.ui.control.NumericEditField
        CrossoverFunctionDropDownLabel  matlab.ui.control.Label
        CrossoverFunctionDropDown       matlab.ui.control.DropDown
        MutationPanel                   matlab.ui.container.Panel
        MutationPercentageEditFieldLabel  matlab.ui.control.Label
        MutationPercentageEditField     matlab.ui.control.NumericEditField
        MutationRateEditFieldLabel      matlab.ui.control.Label
        MutationRateEditField           matlab.ui.control.NumericEditField
        STARTButton                     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        Numberofdecisionvariables % Description
        MaximumnumberofIteration
        PopulationSize
        beta
        tournamentSize
        CrossoverPercentage
        MutationPercentage
        MutationRate
        value
        crossover
       
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
           app.Numberofdecisionvariables= app.NumberofdecisionvariablesEditField.Value;
          % disp(Numberofdecisionvariables)
           app.MaximumnumberofIteration= app.MaximumnumberofIterationEditField.Value;
           app.PopulationSize=app.PopulationSizeEditField.Value;
           app.CrossoverPercentage=app.CrossoverPercentageEditField.Value;
           app.MutationPercentage=app.MutationPercentageEditField.Value;
           app.MutationRate=app.MutationRateEditField.Value;
           x=1;


%            app.NFE=0;
%            app.VarSize = 1-app.Numberofdecisionvariables;
%            app.nc=2*round(app.CrossoverPercentage*app.PopulationSize/2);
%            app.nm = round(app.MutationPercentage*app.PopulationSize);
            [nfe,bestcost]=galgo(app.Numberofdecisionvariables,app.MaximumnumberofIteration,app.PopulationSize,app.CrossoverPercentage,app.MutationPercentage,app.MutationRate,app.beta,app.tournamentSize,app.value,app.crossover);
            plot(app.UIAxes,nfe,bestcost,'LineWidth')
            
        end

        % Value changed function: SelectionFunctionDropDown
        function SelectionFunctionDropDownValueChanged(app, event)
            app.value = app.SelectionFunctionDropDown.Value;
            disp('------------------------------------')
            disp(app.value)
            if app.value == "Tournament"
                app.TournamentSizeEditField.Visible = 'On';
                app.TournamentSizeEditFieldLabel.Visible = 'On';
                app.TournamentSizeEditFieldLabel.Text='Tournament Size';
                drawnow
                app.tournamentSize=app.TournamentSizeEditField.Value;
            elseif app.value == "Roulette Wheel"
                app.TournamentSizeEditField.Visible = 'On';
                app.TournamentSizeEditFieldLabel.Visible = 'On';
                app.TournamentSizeEditFieldLabel.Text='beta';
                drawnow
                app.beta =app.TournamentSizeEditField.Value;
            elseif app.value == "Random"
                app.TournamentSizeEditField.Visible = 'Off';
                app.TournamentSizeEditFieldLabel.Visible = 'Off';
               
            end
        end

        % Value changed function: CrossoverFunctionDropDown
        function CrossoverFunctionDropDownValueChanged(app, event)
            app.crossover = app.CrossoverFunctionDropDown.Value;
            disp(app.crossover)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GeneticAlgorithmPanel = uipanel(app.UIFigure);
            app.GeneticAlgorithmPanel.Title = 'Genetic Algorithm';
            app.NumberofdecisionvariablesEditFieldLabel = uilabel(app.GeneticAlgorithmPanel);
            app.NumberofdecisionvariablesEditFieldLabel.Text = 'Number of decision variables';
            app.NumberofdecisionvariablesEditField = uieditfield(app.GeneticAlgorithmPanel, 'numeric');
            app.MaximumnumberofIterationEditFieldLabel = uilabel(app.GeneticAlgorithmPanel);
            app.MaximumnumberofIterationEditFieldLabel.Text = 'Maximum number of Iteration';
            app.MaximumnumberofIterationEditField = uieditfield(app.GeneticAlgorithmPanel, 'numeric');
            app.PopulationPanel = uipanel(app.GeneticAlgorithmPanel);
            app.PopulationPanel.Title = 'Population';
            app.PopulationSizeEditFieldLabel = uilabel(app.PopulationPanel);
            app.PopulationSizeEditFieldLabel.Text = 'Population Size';
            app.PopulationSizeEditField = uieditfield(app.PopulationPanel, 'numeric');
            app.SelectionPanel = uipanel(app.GeneticAlgorithmPanel);
            app.SelectionPanel.Title = 'Selection';
            app.SelectionFunctionDropDownLabel = uilabel(app.SelectionPanel);
            app.SelectionFunctionDropDownLabel.Text = 'Selection Function';
            app.SelectionFunctionDropDown = uidropdown(app.SelectionPanel);
            app.SelectionFunctionDropDown.Items = {'Roulette Wheel', 'Tournament', 'Random'};
            app.SelectionFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectionFunctionDropDownValueChanged, true);
            app.SelectionFunctionDropDown.Value = 'Random';
            app.TournamentSizeEditFieldLabel = uilabel(app.SelectionPanel);
            app.TournamentSizeEditFieldLabel.Visible = 'off';
            app.TournamentSizeEditFieldLabel.Text = 'Tournament Size';
            app.TournamentSizeEditField = uieditfield(app.SelectionPanel, 'numeric');
            app.TournamentSizeEditField.Visible = 'off';
            app.CrossoverPanel = uipanel(app.GeneticAlgorithmPanel);
            app.CrossoverPanel.Title = 'Crossover';
            app.CrossoverPercentageEditFieldLabel = uilabel(app.CrossoverPanel);
            app.CrossoverPercentageEditFieldLabel.Text = 'Crossover Percentage';
            app.CrossoverPercentageEditField = uieditfield(app.CrossoverPanel, 'numeric');
            app.CrossoverFunctionDropDownLabel = uilabel(app.CrossoverPanel);
            app.CrossoverFunctionDropDownLabel.Text = 'Crossover Function';
            app.CrossoverFunctionDropDown = uidropdown(app.CrossoverPanel);
            app.CrossoverFunctionDropDown.Items = {'Single Point', 'Double Point', 'Uniform', 'Mix'};
            app.CrossoverFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @CrossoverFunctionDropDownValueChanged, true);
            app.CrossoverFunctionDropDown.Value = 'Single Point';
            app.MutationPanel = uipanel(app.GeneticAlgorithmPanel);
            app.MutationPanel.Title = 'Mutation';
            app.MutationPercentageEditFieldLabel = uilabel(app.MutationPanel);
            app.MutationPercentageEditFieldLabel.Text = 'Mutation Percentage';
            app.MutationPercentageEditField = uieditfield(app.MutationPanel, 'numeric');
            app.MutationRateEditFieldLabel = uilabel(app.MutationPanel);
            app.MutationRateEditFieldLabel.Text = 'Mutation Rate';
            app.MutationRateEditField = uieditfield(app.MutationPanel, 'numeric');
            app.STARTButton = uibutton(app.GeneticAlgorithmPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = {'START'; ''};
            app.UIAxes = uiaxes(app.GeneticAlgorithmPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'NFE')
            ylabel(app.UIAxes, 'Cost')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end